classdef Alpha_Solvent_Recovery_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InstructionsTab                matlab.ui.container.Tab
        ClicktheContinueButtontogototheinputsLabel  matlab.ui.control.Label
        ThankyouforDownloadingSolventRecoveryProLabel  matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        Image6_2                       matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        CRowanUniversityAllRightsReservedLabel  matlab.ui.control.Label
        ChemicalInputsTab              matlab.ui.container.Tab
        MassFlowEditField              matlab.ui.control.NumericEditField
        MassFlowEditFieldLabel         matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        OperatingHoursSlider           matlab.ui.control.Slider
        OperatingHoursSliderLabel      matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        ContinueButton_2               matlab.ui.control.Button
        InputChemicalRecoveryContraintsLabel  matlab.ui.control.Label
        CRowanUniversityAllRightsReservedLabel_2  matlab.ui.control.Label
        Image6_10                      matlab.ui.control.Image
        Image6_3                       matlab.ui.control.Image
        ChemicalPresentinDatabaseYesNoEditField  matlab.ui.control.EditField
        kghrLabel_2                    matlab.ui.control.Label
        ChemicalPresentinDatabaseYesNoLabel  matlab.ui.control.Label
        insertButton                   matlab.ui.control.Button
        InputChemicalPropertiesLabel   matlab.ui.control.Label
        ChemicalDatabaseSearchLabel    matlab.ui.control.Label
        MeltingPointEditField          matlab.ui.control.EditField
        MeltingPointEditFieldLabel     matlab.ui.control.Label
        UITable2_2                     matlab.ui.control.Table
        addButton                      matlab.ui.control.Button
        BoilingPointCEditField         matlab.ui.control.EditField
        BoilingPointCEditFieldLabel    matlab.ui.control.Label
        HeatCapacityKJKgCEditField     matlab.ui.control.EditField
        HeatCapacityKJKgCLabel         matlab.ui.control.Label
        HeatofVaporizationKJKgEditField  matlab.ui.control.EditField
        HeatofVaporizationKJKgEditFieldLabel  matlab.ui.control.Label
        MolecularWeightgmolEditField   matlab.ui.control.EditField
        MolecularWeightgmolEditFieldLabel  matlab.ui.control.Label
        Densitykgm3EditField           matlab.ui.control.EditField
        Densitykgm3EditFieldLabel      matlab.ui.control.Label
        NewChemicalNameEditField       matlab.ui.control.EditField
        NewChemicalNameEditFieldLabel  matlab.ui.control.Label
        CreateYourOwnChemicalLabel     matlab.ui.control.Label
        ChemicalNameEditField          matlab.ui.control.EditField
        ChemicalNameEditFieldLabel     matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ChemicalSpecificParametersTab  matlab.ui.container.Tab
        UITable7                       matlab.ui.control.Table
        Image19_2                      matlab.ui.control.Image
        Image6_20                      matlab.ui.control.Image
        Image6_19                      matlab.ui.control.Image
        CRowanUniversityAllRightsReservedLabel_3  matlab.ui.control.Label
        CompileInputsButton            matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        TextArea_7                     matlab.ui.control.TextArea
        TechnologySpecificationsTab_2  matlab.ui.container.Tab
        Image19                        matlab.ui.control.Image
        UITable6                       matlab.ui.control.Table
        SelectTechnologyDropDown       matlab.ui.control.DropDown
        SelectTechnologyDropDownLabel  matlab.ui.control.Label
        Image6_18                      matlab.ui.control.Image
        Image6_17                      matlab.ui.control.Image
        CRowanUniversityAllRightsReservedLabel_4  matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        DefaultsButton_2               matlab.ui.control.Button
        TextArea_6                     matlab.ui.control.TextArea
        OutputsTab                     matlab.ui.container.Tab
        Image6_13                      matlab.ui.control.Image
        Image6_6                       matlab.ui.control.Image
        CRowanUniversityAllRightsReservedLabel_5  matlab.ui.control.Label
        CostBreakdownofallCombinedStagesLabel  matlab.ui.control.Label
        ComponentRecoveryOutputsLabel  matlab.ui.control.Label
        StagesUsedinSolutionLabel      matlab.ui.control.Label
        UITable4_2                     matlab.ui.control.Table
        UITable5                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       ChemicalDatabaseS1 = readtable("Automated_Excel.xlsx","Sheet","Chem_Database","Range","A1:G500","ReadVariableNames",true,'PreserveVariableNames',true);
       
       %how to run the gams code system 'gams trnsport lo=3 gdx=blah'
       
       %"G:\Shared drives\Solvent Recovery Clinic\matlab GUI\Jake Demo\Master_Excel_Solv_Rec_FINAL.xlsx"
       %irgdx(filename,desired parameter)
       %newpath=addpath('C:\GAMS\36') 
       %TestFile=irgdx('transport_gdx','capacity','demand')
       ADS_Columns= readvars("Automated_Excel.xlsx","Sheet","ADS","Range","R5:R12");
%        ATPE_Columns= readvars("Automated_Excel.xlsx","Sheet","ADS","Range","R5:R12");
       CNF_Columns= readvars("Automated_Excel.xlsx","Sheet","CNF","Range","R5:R7");
       DST_Columns= readvars("Automated_Excel.xlsx","Sheet","DST","Range","R5:R14");
%        DRY_Columns= readvars("Automated_Excel.xlsx","Sheet","ADS","Range","R5:R12");
       FLT_Columns= readvars("Automated_Excel.xlsx","Sheet","FLT","Range","R5:R7");
       MF_Columns= readvars("Automated_Excel.xlsx","Sheet","MF","Range","R5:R6");
       NF_Columns= readvars("Automated_Excel.xlsx","Sheet","NF","Range","R5:R7");
       UF_Columns= readvars("Automated_Excel.xlsx","Sheet","UF","Range","R5:R6");
%        INCN_Columns= readvars("Automated_Excel.xlsx","Sheet","ADS","Range","R5:R12");
       PRC_Columns= readvars("Automated_Excel.xlsx","Sheet","PRC","Range","R5:R7");
       PVP_Columns= readvars("Automated_Excel.xlsx","Sheet","PVP","Range","R5:R6");
       SDM_Columns= readvars("Automated_Excel.xlsx","Sheet","SDM","Range","R5:R10");
      
       ADS_Data= ["Unitless","Hours","Hours","$/kg","Unitless","kg/m^3","hours","m/m";
                  0.7,1,720,24,0.4,4,0.01666667,0.5;
                  0.7,1,720,24,0.4,4,0.01666667,0.5];
              
%        ATPE_Data=[2];
       CNF_Data=["Unitless","°C","°C";
                 0.7,20,30];
       DST_Data=["unitless", "m/s", "unitless", "m", " °C", "kJ/kg", "g/mol", "kJ/kg°C", "°C", "°C";
                 1,10800,0.8,1.5,1.3,2257,18,4.2,250,20];
%        Dry_Data=[2];
       FLT_Data=["m^3/m^2 s", "$/m^2", "hr";
                0.2,100,100];
       MF_Data=["m^3/m^2 s", "$/m^2";
                0.0856,736;
                0.0856,736];
       NF_Data=["m^3/m^2 s", "hr", "$/m^2";
                0.2,4000,100];
       UF_Data=["m^3/m^2 s", "$/m^2";
                0.0856,736;
                0.0856,736];
%        INCN_Data=[2];
       PRC_Data=["kg/m^3", "unitless", "$/kg";
                 1000,2,0.3];
       PVP_Data=["m^3/m^2 s", "$/m^2";
                 0.055,1000;
                 0.055,1000;
                 0.055,1000];
            %these values need to be taken from the excel sheet!!!
       SDM_Data=["hr", "unitless", "m", "Pa*s", "kg/m^3", "kg/m^3";
                 0.5,0.7,0.00025,0.00089,0,0];
       
             
       myFSW          %FileSystemWatcher object
 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable6.ColumnName= app.ADS_Columns;
           app.UITable6.Data=app.ADS_Data;
           app.UITable6.RowName={'Units','ADS1','ADS2'};
           app.UITable6.ColumnEditable = true;           
            

        end

        % Button pushed function: insertButton
        function insertButtonPushed(app, event)
            str="Yes"; 
            Answer=app.ChemicalPresentinDatabaseYesNoEditField.Value;
            if contains(str,Answer)
                Chemical_List=app.ChemicalDatabaseS1.Chemical;
                chem_desired=app.ChemicalNameEditField.Value;
                Find_Array=strcmpi(Chemical_List,chem_desired);
                find_fun=find(Find_Array==1);
                Chem_prop=app.ChemicalDatabaseS1(find_fun,(1:7));
                app.UITable.RowName= 'numbered';
                app.UITable.Data=[app.UITable.Data;Chem_prop];         
%%For table 2            
                T2=app.UITable2_2.Data;
 %              numRow2=size(T2,1)+1;
                states=categorical({'-'},{'-','Solid','Liquid'}, 'Protected', true);
                desired=categorical({'-'},{'-','Yes','No'}, 'Protected', true);
                constraints=categorical({'-'},{'-','Purity','Recovery','No Constraint'}, 'Protected', true);
                %states=categorical(["no","Yes"]);
                %other_data=[0,0];
                newData2=table(app.ChemicalDatabaseS1.Chemical(find_fun),states,desired,constraints,0,0);
                %newData2={"Yrs", states,0,0};
                
                %newData2={char(app.ChemicalDatabaseS1.Chemical(find_fun)), states,0,0};%initialize new row
                %newData2.state=categorical(newData2.state,{'no','yes'},'Ordinal',false);
                app.UITable2_2.RowName= 'numbered';
                app.UITable2_2.Data=[T2;newData2];
                
                
%%For table 3
                T3=app.UITable7.Data;
                newData3=table(app.ChemicalDatabaseS1.Chemical(find_fun),0,0,0,0,0,0,0,0,0,0,0,0,0);
                app.UITable7.RowName= 'numbered';
                app.UITable7.Data=[T3;newData3];                
                
            else 
                
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
             T=app.UITable.Data;
             %numRow=size(T,1)+1;
             newData={app.NewChemicalNameEditField.Value, app.Densitykgm3EditField.Value,app.MolecularWeightgmolEditField.Value,app.HeatofVaporizationKJKgEditField.Value,app.HeatCapacityKJKgCEditField.Value,app.BoilingPointCEditField.Value,app.MeltingPointEditField.Value};%initialize new row
             app.UITable.RowName= 'numbered';
             app.UITable.Data=[T;newData];
%%For table 2             
             T2=app.UITable2_2.Data;
            % numRow2=size(T2,1)+1;
             newData2={app.NewChemicalNameEditField.Value, 0,0,0};%initialize new row
             app.UITable2_2.RowName= 'numbered';
             app.UITable2_2.Data=[T2;newData2];
        end

        % Button pushed function: ContinueButton_2
        function ContinueButton_2Pushed(app, event)
            Property_Values = app.UITable.Data;
            Constraint_values = app.UITable2_2.Data;
            Chem_spec_parms=app.UITable7.Data;
            Desired_table=Constraint_values(Constraint_values.desired == "Yes",:);
            desired_chems=table2array(Desired_table(:,1));
            all_chems=table2array(Chem_spec_parms(:,1));
            Constraint_values.Properties.VariableNames=["Name1","State","Desired","Constraint","Con_val","MassFraction"];
            
            full_Table=[Property_Values,Constraint_values];
            

            Liquid_table=full_Table(full_Table.State == "Liquid",:); 
            highest_frac=max(table2array(Liquid_table(:,end)));
            liquid_props=Liquid_table(Liquid_table.MassFraction == highest_frac,:);
            liquid_den=table2array(liquid_props(:,2));
            liq_den_max=max(liquid_den);
            
            Solid_table=full_Table(full_Table.State == "Solid",:);
            if isempty(Solid_table)
               solid_den=liq_den_max;
            else 
               solid_den=max(table2array(Solid_table(:,2))); 
            end
%             class(liquid_den)
%             disp(liquid_den)
%             
%             class(solid_den)
%             disp(solid_den)
            
            app.SDM_Data(2,5)=solid_den;
            app.SDM_Data(2,6)=liq_den_max;
            

            for c=1:length(all_chems)
                if Constraint_values(c,2).State=="Solid" && contains(all_chems(c),desired_chems)
 
                    Chem_spec_parms(c,2:14)={0.9};
                    Chem_spec_parms(c,[2,3,4])={0.95};
                elseif Constraint_values(c,2).State=="Liquid" && contains(all_chems(c),desired_chems)
                    Chem_spec_parms(c,2:14)={0.05};
                    Chem_spec_parms(c,[2,3,4])={0.06};
%                 elseif contains(all_chems(c),desired_chems)
%                     Chem_spec_parms(c,2:14)={0.7};
                else 
                    %disp(Constraint_values.states=="Solid" & contains(all_chems(c),desired_chems))
                    Chem_spec_parms(c,2:14)={0.4};
                end

            end

            app.UITable7.Data=Chem_spec_parms;
            pause(0.1)
            
            app.TabGroup.SelectedTab=app.ChemicalSpecificParametersTab;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%OLD GENERATE SOLUTION FUNCTION%%%%%%
            
            %https://www.mathworks.com/help/matlab/ref/pie.html
            %how to run the gams code system 'gams trnsport lo=3 gdx=blah'
%             Property_Values = app.UITable.Data;
%             Constraint_values = app.UITable2_2.Data;
%             Constraint_values(:,1)=[];
%             Combined_table=[];
%             Blank=strings(30,10);
%             Combined_table=[Combined_table,Property_Values,Constraint_values];
%             Mass_Flow=app.TotalMassFlowEditField_2.Value;
%             %writematrix(Blank,"G:\Shared drives\Solvent Recovery Clinic\matlab GUI\Jake Demo\Master_Excel_Solv_Rec_FINAL.xlsx",'Sheet','Matlab_Imports', 'Range', 'A3:K30');
%             %writematrix(Mass_Flow,"G:\Shared drives\Solvent Recovery Clinic\matlab GUI\Jake Demo\Master_Excel_Solv_Rec_FINAL.xlsx",'Sheet','Matlab_Imports', 'Range', 'B1');
%             %writetable(Combined_table,"G:\Shared drives\Solvent Recovery Clinic\matlab GUI\Jake Demo\Master_Excel_Solv_Rec_FINAL.xlsx",'Sheet','Matlab_Imports', 'Range', 'A3:K30','WriteVariableNames',false);
%             %system('gams GDX_Master_Excel_Import');
%             %answer=system('gams trnsport');
%             pause(7)
%             
%             %https://www.mathworks.com/help/matlab/ref/pie.html
%             app.UITable3.Data=app.Stage_Data;
%             p=pie(app.UIAxes, (str2double(app.Stage_Data(:,4))));
%             pText = findobj(p,'Type','text');
%             percentValues = get(pText,'String'); 
%             txt = app.Stage_Data(:,1); 
%             combinedtxt = strcat(txt,", ",percentValues);
%             %if percentValues=='>1';
%                 
%             %end
%             for c=1:length(combinedtxt)
%                 pText(c).String=combinedtxt(c);
%             end            
%             
%             app.UITable5.Data=app.Chemical_data;
%             
%             
%             app.UITable4_2.Data=app.Overall_Data;
%             p2=pie(app.UIAxes_2, (str2double(app.Overall_Data(:,2))));
%             p2Text = findobj(p2,'Type','text');
%             percentValues = get(p2Text,'String'); 
%             txt = app.Overall_Data(:,1); 
%             combinedtxt = strcat(txt,", ",percentValues); 
%             for c=1:length(combinedtxt)
%                 p2Text(c).String=combinedtxt(c);
%             end            
%             
%             app.TabGroup.SelectedTab=app.OutputsTab;
        end

        % Callback function
        function ImportGDXButtonPushed(app, event)
%             app.Value1EditField.Value=app.TestFile.capacity(1,1);
%             app.Value2EditField.Value=app.TestFile.capacity(2,1);
        end

        % Value changing function: ChemicalNameEditField
        function ChemicalNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %Transforms a list of the chemical names into an array
            Chemical_List=app.ChemicalDatabaseS1.Chemical;
            %Takes the value of the edit field in the app
            chem_desired=changingValue;
            %app.ChemicalNameEditField.Value;
            %Makes an array comparing the value from the edit field and
            %compares it to the chemcial list. Returns 1 is it is there 0
            %if it is not. This function is used to make it case
            %insensitive
            Find_Array=strcmpi(Chemical_List,chem_desired);
            %Sums the whole array from the equation above
            Find_Array_Sum = sum(Find_Array);
            %If the sum is 1 the answer is yes, anything else answer is 0
            if Find_Array_Sum==1
                Answer="Yes";
            else
                Answer="No";
            end
            %returns the answer value to the yes/no field
            app.ChemicalPresentinDatabaseYesNoEditField.Value=Answer;
        end

        % Button pushed function: DefaultsButton_2
        function DefaultsButton_2Pushed(app, event)
       app.ADS_Data= ["Unitless","Hours","Hours","$/kg","Unitless","kg/m^3","hours","m/m";
                  0.7,1,720,24,0.4,4,0.01666667,0.5;
                  0.7,1,720,24,0.4,4,0.01666667,0.5];
              
       app.CNF_Data=["Unitless","°C","°C";
                 0.7,20,30];
       app.DST_Data=["unitless", "m/s", "unitless", "m", " °C", "kJ/kg", "g/mol", "kJ/kg°C", "°C", "°C";
                 1,10800,0.8,1.5,1.3,2257,18,4.2,250,20];

       app.FLT_Data=["m^3/m^2 s", "$/m^2", "hr";
                0.2,100,100];
       app.MF_Data=["m^3/m^2 s", "$/m^2";
                0.0856,736;
                0.0856,736];
       app.NF_Data=["m^3/m^2 s", "hr", "$/m^2";
                0.2,4000,100];
       app.UF_Data=["m^3/m^2 s", "$/m^2";
                0.0856,736;
                0.0856,736];

       app.PRC_Data=["kg/m^3", "unitless", "$/kg";
                 1000,2,0.3];
       app.PVP_Data=["m^3/m^2 s", "$/m^2";
                 0.055,1000;
                 0.055,1000;
                 0.055,1000];

       app.SDM_Data=["hr", "unitless", "m", "Pa*s", "kg/m^3", "kg/m^3";
                 0.5,0.7,0.00025,0.00089,0,0];
       if isempty(app.UITable.Data) 
          disp("you empty!") 
       else
           Property_Values = app.UITable.Data;
           Constraint_values = app.UITable2_2.Data;
           Constraint_values.Properties.VariableNames=["Name1","State","Desired","Constraint","Con_val","MassFraction"];
           
           full_Table=[Property_Values,Constraint_values];
            
           Liquid_table=full_Table(full_Table.State == "Liquid",:); 
           highest_frac=max(table2array(Liquid_table(:,end)));
           liquid_props=Liquid_table(Liquid_table.MassFraction == highest_frac,:);
           liquid_den=table2array(liquid_props(:,2));
            
           Solid_table=full_Table(full_Table.State == "Solid",:);
           if isempty(Solid_table)
               solid_den=liquid_den;
           else 
               solid_den=max(table2array(Solid_table(:,2))); 
           end
    
           app.SDM_Data(2,5)={solid_den};
           app.SDM_Data(2,6)={liquid_den};
       end
       value = app.SelectTechnologyDropDown.Value;
        if strcmp(value, 'Adsorption')
            app.UITable6.ColumnName= app.ADS_Columns;
            app.UITable6.Data=app.ADS_Data;
            app.UITable6.RowName={'Units','ADS1','ADS2'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Sedimentation')
            app.UITable6.ColumnName= app.SDM_Columns;
            app.UITable6.Data=app.SDM_Data;
            app.UITable6.RowName={'Units','SDM'};
            app.UITable6.ColumnEditable = true; 
            
        elseif strcmp(value, 'Percipitation')
            app.UITable6.ColumnName= app.PRC_Columns;
            app.UITable6.Data=app.PRC_Data;
            app.UITable6.RowName={'Units','PRC'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Centrifugation')
            app.UITable6.ColumnName= app.CNF_Columns;
            app.UITable6.Data=app.CNF_Data;
            app.UITable6.RowName={'Units','CNF'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Distillation')
            app.UITable6.ColumnName= app.DST_Columns;
            app.UITable6.Data=app.DST_Data;
            app.UITable6.RowName={'Units','DST'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Filter')
            app.UITable6.ColumnName= app.FLT_Columns;
            app.UITable6.Data=app.FLT_Data;
            app.UITable6.RowName={'Units','FLT'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Microfiltation')
            app.UITable6.ColumnName= app.MF_Columns; 
            app.UITable6.Data=app.MF_Data;
            app.UITable6.RowName={'Units','MF1','MF2'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Pervaporation')
            app.UITable6.ColumnName= app.PVP_Columns; 
            app.UITable6.Data=app.PVP_Data;
            app.UITable6.RowName={'Units','PVP1','PVP2','PVP3'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'ultrafiltration')
            app.UITable6.ColumnName= app.UF_Columns;  
            app.UITable6.Data=app.UF_Data;
            app.UITable6.RowName={'Units','UF1','UF2'};
            app.UITable6.ColumnEditable = true;
            
        elseif strcmp(value, 'Nanofiltration')
            app.UITable6.ColumnName= app.NF_Columns;
            app.UITable6.Data=app.NF_Data;
            app.UITable6.RowName={'Units','NF'};
            app.UITable6.ColumnEditable = true;                
        end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            value = app.SelectTechnologyDropDown.Value;
            if strcmp(value, 'Adsorption')
                app.ADS_Data=app.UITable6.Data;
            elseif strcmp(value, 'Sedimentation')
                app.SDM_Data=app.UITable6.Data;
            elseif strcmp(value, 'Percipitation')
                app.PRC_Data=app.UITable6.Data;
            elseif strcmp(value, 'Centrifugation')
                app.CNF_Data=app.UITable6.Data;
            elseif strcmp(value, 'Distillation')
                app.DST_Data=app.UITable6.Data;
            elseif strcmp(value, 'Filter')
                app.FLT_Data=app.UITable6.Data;
            elseif strcmp(value, 'Microfiltation')
                app.MF_Data=app.UITable6.Data;
            elseif strcmp(value, 'Pervaporation')
                app.PVP_Data=app.UITable6.Data;
            elseif strcmp(value, 'ultrafiltration')
                app.UF_Data=app.UITable6.Data;
            elseif strcmp(value, 'Nanofiltration')
                app.NF_Data=app.UITable6.Data;
            end
            %https://www.mathworks.com/help/matlab/ref/msgbox.html
            msgbox("New Data Saved");
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ChemicalInputsTab;
        end

        % Value changed function: SelectTechnologyDropDown
        function SelectTechnologyDropDownValueChanged(app, event)
            value = app.SelectTechnologyDropDown.Value;
            %'Adsorption', 'Sedimentation', 'Percipitation', 'Centrifugation', 'Distillation', 'Filter', 'Microfiltation', 'Pervaporation', 'ultrafiltration', 'Nanofiltration'

            if strcmp(value, 'Adsorption')
                app.UITable6.ColumnName= app.ADS_Columns;
                app.UITable6.Data=app.ADS_Data;
                app.UITable6.RowName={'Units','ADS1','ADS2'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Sedimentation')
                app.UITable6.ColumnName= app.SDM_Columns;
                app.UITable6.Data=app.SDM_Data;
                app.UITable6.RowName={'Units','SDM'};
                app.UITable6.ColumnEditable = true; 
                
            elseif strcmp(value, 'Percipitation')
                app.UITable6.ColumnName= app.PRC_Columns;
                app.UITable6.Data=app.PRC_Data;
                app.UITable6.RowName={'Units','PRC'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Centrifugation')
                app.UITable6.ColumnName= app.CNF_Columns;
                app.UITable6.Data=app.CNF_Data;
                app.UITable6.RowName={'Units','CNF'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Distillation')
                app.UITable6.ColumnName= app.DST_Columns;
                app.UITable6.Data=app.DST_Data;
                app.UITable6.RowName={'Units','DST'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Filter')
                app.UITable6.ColumnName= app.FLT_Columns;
                app.UITable6.Data=app.FLT_Data;
                app.UITable6.RowName={'Units','FLT'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Microfiltation')
                app.UITable6.ColumnName= app.MF_Columns; 
                app.UITable6.Data=app.MF_Data;
                app.UITable6.RowName={'Units','MF1','MF2'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Pervaporation')
                app.UITable6.ColumnName= app.PVP_Columns; 
                app.UITable6.Data=app.PVP_Data;
                app.UITable6.RowName={'Units','PVP1','PVP2','PVP3'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'ultrafiltration')
                app.UITable6.ColumnName= app.UF_Columns;  
                app.UITable6.Data=app.UF_Data;
                app.UITable6.RowName={'Units','UF1','UF2'};
                app.UITable6.ColumnEditable = true;
                
            elseif strcmp(value, 'Nanofiltration')
                app.UITable6.ColumnName= app.NF_Columns;
                app.UITable6.Data=app.NF_Data;
                app.UITable6.RowName={'Units','NF'};
                app.UITable6.ColumnEditable = true;                
            end

        end

        % Button pushed function: CompileInputsButton
        function CompileInputsButtonPushed(app, event)
            property_values = app.UITable.Data;
            input_values = app.UITable2_2.Data;
            input_values.Properties.VariableNames=["Name1","State","Desired","Constraint","Con_val","Mass Fraction"];
            ret_values = app.UITable7.Data;
            ret_vars=["Name2","RFLT1", "Bind_ADS1", "Eff_PRC", "alpha", "RCpvp1", "Bind_ADS2", "RCmf1", "RCmf2", "RCuf1", "RCpvp2", "RCuf2", "RCnf", "RCpvp3",];
            ret_values.Properties.VariableNames=ret_vars;
%             property_values=table2array(property_values_table(1,:));
    
            all_data=[property_values,input_values,ret_values];
            intermediate_table=removevars(all_data,["Name1","Name2"]);
            %disp(intermediate_table)
            Solid_table=intermediate_table(intermediate_table.State == "Solid",:);
            %disp(Solid_table)
            Liquid_table=intermediate_table(intermediate_table.State == "Liquid",:);
            %disp(Liquid_table)      
            
            Desired_table=input_values(input_values.Desired == "Yes",:);
            desired_chems=table2array(Desired_table(:,1));
            all_chems=table2array(ret_values(:,1));      
            
            dks_chem=[];
            dko_chem=[];
            dks_pur=[];
            dko_pur=[];
            dks_rec=[];
            dko_rec=[];
                      
            for c=1:length(all_chems)
                if input_values(c,2).State=="Solid" && contains(all_chems(c),desired_chems)
                    %dko_chem.value=input_values(c,1);
                    dko_chem=[dko_chem;table2array(input_values(c,1))];
                    if input_values(c,4).Constraint=="Purity"
                        dko_pur=[dko_pur;table2array(input_values(c,5))];
                        dko_rec=[dko_rec;0.001];
                    elseif input_values(c,4).Constraint=="Recovery"
                        dko_pur=[dko_pur;0.001];
                        dko_rec=[dko_rec;table2array(input_values(c,5))];                        
                    end
                elseif input_values(c,2).State=="Liquid" && contains(all_chems(c),desired_chems)
                    dks_chem=[dks_chem;table2array(input_values(c,1))];
                    if input_values(c,4).Constraint=="Purity"
                        dks_pur=[dks_pur;table2array(input_values(c,5))];
                        dks_rec=[dks_rec;0.001];
                    elseif input_values(c,4).Constraint=="Recovery"
                        dks_pur=[dks_pur;0.001];
                        dks_rec=[dks_rec;table2array(input_values(c,5))];                        
                    end
                end

            end            
            
            dko=table(dko_chem,dko_pur,dko_rec);
            dko.Properties.VariableNames=["dkO","Pur","Rec"];
            dks=table(dks_chem,dks_pur,dks_rec);
            dks.Properties.VariableNames=["dkS","Pur","Rec"];

            Blank=strings(30,30);
            chem_number=length(all_chems);
            %10 columns to erase the chemical names
            blank_CMSTR=strings(60,10);
            CMSTR=ones(59,chem_number);
            %10 rows to erase the chemical names
            blank_CMUNT=strings(10,29);
            CMUNT=ones(chem_number,28);
            
            MassFlow=app.MassFlowEditField.Value;
            disp(MassFlow)
            OPhours=app.EditField.Value;

            writematrix(Blank,"Automated_Excel.xlsx","Sheet","Chem_Props", "Range", "A2")
            writetable(intermediate_table,"Automated_Excel.xlsx","Sheet","Chem_Props", "Range", "A2");
            writematrix(Blank,"Automated_Excel.xlsx","Sheet","Solid_Props", "Range", "A2");
            writetable(Solid_table,"Automated_Excel.xlsx","Sheet","Solid_Props", "Range", "A2");
            writematrix(Blank,"Automated_Excel.xlsx","Sheet","Sol_Props", "Range", "A2");
            writetable(Liquid_table,"Automated_Excel.xlsx","Sheet","Sol_Props", "Range", "A2");
            
            
            writematrix(Blank,"Automated_Excel.xlsx","Sheet","Outlet_Spec", "Range", "A1");
            writetable(dko,"Automated_Excel.xlsx","Sheet","Outlet_Spec", "Range", "F1");
            writetable(dks,"Automated_Excel.xlsx","Sheet","Outlet_Spec", "Range", "A1");


            writematrix(blank_CMSTR,"Automated_Excel.xlsx","Sheet","CMSTR_mat", "Range", "B1")
            writematrix(blank_CMUNT,"Automated_Excel.xlsx","Sheet","CMUNT_mat", "Range", "A2")
            writecell(transpose(all_chems),"Automated_Excel.xlsx","Sheet","CMSTR_mat", "Range", "B1")
            writecell(all_chems,"Automated_Excel.xlsx","Sheet","CMUNT_mat", "Range", "A2")
            writematrix(CMSTR,"Automated_Excel.xlsx","Sheet","CMSTR_mat", "Range", "B2")
            writematrix(CMUNT,"Automated_Excel.xlsx","Sheet","CMUNT_mat", "Range", "B2")            
            
            writematrix(MassFlow,"Automated_Excel.xlsx","Sheet","Chem_Props", "Range", "B1")
            writematrix(OPhours,"Automated_Excel.xlsx","Sheet","mat_data", "Range", "B5")
            
            pause(5)
            
            msgbox("All Inputs Compiled");
            
            app.RunButton.Enable = "on";
            %when file is completed enable the run button
        end

        % Value changing function: OperatingHoursSlider
        function OperatingHoursSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=changingValue;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.OperatingHoursSlider.Value=value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %clear the excel file
            
            %progress bar
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Running File',"Indeterminate","on");
                
                
            start_file=dir('G:\Shared drives\Solvent Recovery Clinic\GUI - Alpha Version\results.xlsx');
               
            %running file
            system 'gams Automated_Superstructure lo=3'
            %gdx=results'
            d.Value=0.33;
            d.Message = 'Computation Completed';
            
            
            %check if the file changes
            count=1;
            while count<10000
                count=count+1;
                end_file=dir('G:\Shared drives\Solvent Recovery Clinic\GUI - Alpha Version\results.xlsx');
                if mean(start_file.date==end_file.date)<1
                    count=10001;
                else
                    pause(0.1)
                end 
            end
            
            d.Value=0.5;
            d.Message = 'Retireving Results';
            pause(1)
            
            T1=readtable('results.xlsx','sheet','Costs','range','G5:Y6','ReadVariableNames',true);
            all_tech=rows2vars(T1);
            %create individual tables for each stage
            St1=all_tech(all_tech.OriginalVariableNames == "SDM" | all_tech.OriginalVariableNames == "PRC"| all_tech.OriginalVariableNames == "ADS1"| all_tech.OriginalVariableNames == "CNF"| all_tech.OriginalVariableNames == "FLT1"| all_tech.OriginalVariableNames == "BYP1",:);
            St2=all_tech(all_tech.OriginalVariableNames == "DST" | all_tech.OriginalVariableNames == "PVP1"| all_tech.OriginalVariableNames == "ADS2"| all_tech.OriginalVariableNames == "MF1"| all_tech.OriginalVariableNames == "BYP2",:);
            St3=all_tech(all_tech.OriginalVariableNames == "MF2" | all_tech.OriginalVariableNames == "UF1"| all_tech.OriginalVariableNames == "PVP2"|  all_tech.OriginalVariableNames == "BYP3",:);
            St4=all_tech(all_tech.OriginalVariableNames == "UF2" | all_tech.OriginalVariableNames == "NF"| all_tech.OriginalVariableNames == "PVP3"|  all_tech.OriginalVariableNames == "BYP4",:);
            %create a table where it is only yes answers in order of the stages
            all_yes=[St1(St1.Var1==1,:);St2(St2.Var1==1,:);St3(St3.Var1==1,:);St4(St4.Var1==1,:)];
            
            %create table that will show in the app
            T2=readtable('results.xlsx','sheet','Costs','range','G2:J3','ReadVariableNames',true);
            Stages_used=rows2vars(T2);
            Stages_used.Properties.VariableNames=["Stages","Cost"];
            Stages_used.Stages=["Stage 1";"Stage 2";"Stage 3";"Stage 4"];
            Stages_used.Bypass=["string";"string";"string";"string"];
            Stages_used.Tech=all_yes.OriginalVariableNames;
            Stages_used.Cost=Stages_used.Cost*1000000;
            str=strings(4,1);
            for i=1:height(Stages_used)
                if contains(Stages_used.Tech(i),"BYP")
                    str(i)="Yes";
                else
                    str(i)="No";
                end 
            end 
            
            Stages_used.Bypass=str;
            Stages_used=[Stages_used(:,1),Stages_used(:,3),Stages_used(:,4),Stages_used(:,2)];
            
            
            MassFlow=readtable('results.xlsx','sheet','MassFlow','range','A1:J60','ReadVariableNames',true,'PreserveVariableNames',true);
            MassFlow=rmmissing(MassFlow,2);
            MassFlow_T=rows2vars(MassFlow);
            MassFlow_T.Properties.VariableNames=table2array(MassFlow_T(1,:));
            MassFlow_T(1,:)=[];
            tot_cost=readvars('results.xlsx','sheet','Costs','range','B8:B8');
            
            MassCalc=MassFlow_T(:,[1,[2,58+1]]);
            MassCalc.Properties.VariableNames=["Chems","Input","Output58"];
            
            total_out=sum(cell2mat(MassCalc.Output58(1:end)));
            
            MassCalc.recovery=cell2mat(MassCalc.Output58)./cell2mat(MassCalc.Input)*100;
            MassCalc.purity=cell2mat(MassCalc.Output58)./total_out*100;
            %%need to figure this out
            MassCalc.cost=tot_cost./cell2mat(MassCalc.Output58)*1000000;
            
            final_mass=[MassCalc(:,1),MassCalc(:,3),MassCalc(:,4),MassCalc(:,5),MassCalc(:,6)];
            
            d.Value=.75;
            d.Message = 'Making it look pretty';
            pause(1)
            
            Total_costs=readtable('results.xlsx','sheet','Costs','range','A1:B7','ReadVariableNames',true,'PreserveVariableNames',true);
            Total_costs.Cost=Total_costs.Cost.*1000000;
            %https://www.mathworks.com/help/matlab/ref/pie.html
            %https://www.mathworks.com/matlabcentral/answers/319428-how-can-i-create-a-pie-chart-legend-in-the-matlab-gui
            Costs=Total_costs.Cost;
            pie(app.UIAxes_2,Costs);
            legend(app.UIAxes_2,Total_costs.Category,"Location",'eastoutside', ...
                'Orientation',"vertical","NumColumns",1);
            axis(app.UIAxes_2,'off')
            
            bar(app.UIAxes,categorical(Stages_used.Stages),Stages_used.Cost);
            
            app.UIAxes.Visible = 'on';
            app.UIAxes_2.Visible = 'on';
            
            app.UITable3.Data=Stages_used;
            app.UITable5.Data=final_mass;
            app.UITable4_2.Data=Total_costs;
            
            d.Value=1;
            d.Message = 'Done!';
            pause(1)
            
            app.TabGroup.SelectedTab=app.OutputsTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.CRowanUniversityAllRightsReservedLabel = uilabel(app.InstructionsTab);
            app.CRowanUniversityAllRightsReservedLabel.Text = '(C) Rowan University, All Rights Reserved';
            app.Image6 = uiimage(app.InstructionsTab);
            app.Image6.ImageSource = 'Rowan Image.jpg';
            app.Image6_2 = uiimage(app.InstructionsTab);
            app.Image6_2.ImageSource = 'EPA log.png';
            app.ContinueButton = uibutton(app.InstructionsTab, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ThankyouforDownloadingSolventRecoveryProLabel = uilabel(app.InstructionsTab);
            app.ThankyouforDownloadingSolventRecoveryProLabel.Text = 'Thank you for Downloading Solvent Recovery Pro!';
            app.ClicktheContinueButtontogototheinputsLabel = uilabel(app.InstructionsTab);
            app.ClicktheContinueButtontogototheinputsLabel.Text = 'Click the Continue Button to go to the inputs';
            app.ChemicalInputsTab = uitab(app.TabGroup);
            app.ChemicalInputsTab.Title = 'Chemical Inputs';
            app.UITable = uitable(app.ChemicalInputsTab);
            app.UITable.ColumnName = {'Chemical'; 'Density [kg/m^3]'; 'Molecular Weight [g/mol]'; 'Heat of Vaporization [KJ/Kg]'; 'Heat Capacity [KJ/Kg*C]'; 'Boiling Point[°C]'; 'Melting Point[°C]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true];
            app.ChemicalNameEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.ChemicalNameEditFieldLabel.Text = 'Chemical Name';
            app.ChemicalNameEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.ChemicalNameEditField.ValueChangingFcn = createCallbackFcn(app, @ChemicalNameEditFieldValueChanging, true);
            app.CreateYourOwnChemicalLabel = uilabel(app.ChemicalInputsTab);
            app.CreateYourOwnChemicalLabel.Text = 'Create Your Own Chemical';
            app.NewChemicalNameEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.NewChemicalNameEditFieldLabel.Text = 'New Chemical Name';
            app.NewChemicalNameEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.Densitykgm3EditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.Densitykgm3EditFieldLabel.Text = 'Density [kg/m3] ';
            app.Densitykgm3EditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.MolecularWeightgmolEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.MolecularWeightgmolEditFieldLabel.Text = 'Molecular Weight [g/mol]';
            app.MolecularWeightgmolEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.HeatofVaporizationKJKgEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.HeatofVaporizationKJKgEditFieldLabel.Text = 'Heat of Vaporization [KJ/Kg]';
            app.HeatofVaporizationKJKgEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.HeatCapacityKJKgCLabel = uilabel(app.ChemicalInputsTab);
            app.HeatCapacityKJKgCLabel.Text = 'Heat Capacity [KJ/Kg°C]';
            app.HeatCapacityKJKgCEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.BoilingPointCEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.BoilingPointCEditFieldLabel.Text = 'Boiling Point [°C]';
            app.BoilingPointCEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.addButton = uibutton(app.ChemicalInputsTab, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.UITable2_2 = uitable(app.ChemicalInputsTab);
            app.UITable2_2.ColumnName = {'Chemical'; 'State'; 'Desired Chemical?'; 'Purity/ Recovery'; 'Constraint Value'; 'Mass Fraction'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = [false true true true true true];
            app.MeltingPointEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.MeltingPointEditFieldLabel.Text = 'Melting Point [°C]';
            app.MeltingPointEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.ChemicalDatabaseSearchLabel = uilabel(app.ChemicalInputsTab);
            app.ChemicalDatabaseSearchLabel.Text = 'Chemical Database Search';
            app.InputChemicalPropertiesLabel = uilabel(app.ChemicalInputsTab);
            app.InputChemicalPropertiesLabel.Text = 'Input Chemical Properties';
            app.insertButton = uibutton(app.ChemicalInputsTab, 'push');
            app.insertButton.ButtonPushedFcn = createCallbackFcn(app, @insertButtonPushed, true);
            app.insertButton.Text = 'insert';
            app.ChemicalPresentinDatabaseYesNoLabel = uilabel(app.ChemicalInputsTab);
            app.ChemicalPresentinDatabaseYesNoLabel.Text = 'Chemical Present in Database? [Yes/No]:  ';
            app.kghrLabel_2 = uilabel(app.ChemicalInputsTab);
            app.kghrLabel_2.Text = '[kg/hr]';
            app.ChemicalPresentinDatabaseYesNoEditField = uieditfield(app.ChemicalInputsTab, 'text');
            app.ChemicalPresentinDatabaseYesNoEditField.Editable = 'off';
            app.Image6_3 = uiimage(app.ChemicalInputsTab);
            app.Image6_3.ImageSource = 'Rowan Image.jpg';
            app.Image6_10 = uiimage(app.ChemicalInputsTab);
            app.Image6_10.ImageSource = 'EPA log.png';
            app.CRowanUniversityAllRightsReservedLabel_2 = uilabel(app.ChemicalInputsTab);
            app.CRowanUniversityAllRightsReservedLabel_2.Text = '(C) Rowan University, All Rights Reserved';
            app.InputChemicalRecoveryContraintsLabel = uilabel(app.ChemicalInputsTab);
            app.InputChemicalRecoveryContraintsLabel.Text = 'Input Chemical Recovery Contraints';
            app.ContinueButton_2 = uibutton(app.ChemicalInputsTab, 'push');
            app.ContinueButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_2Pushed, true);
            app.ContinueButton_2.Text = 'Continue';
            app.TextArea_3 = uitextarea(app.ChemicalInputsTab);
            app.TextArea_3.Value = {'*Disclaimer: By hitting this Generate Solution button you are using the default values set in the application. you can change these default values by going to the Pathway Selector tab and the Technology Specifications tab'};
            app.OperatingHoursSliderLabel = uilabel(app.ChemicalInputsTab);
            app.OperatingHoursSliderLabel.Text = 'Operating Hours';
            app.OperatingHoursSlider = uislider(app.ChemicalInputsTab);
            app.OperatingHoursSlider.Limits = [0 8760];
            app.OperatingHoursSlider.ValueChangingFcn = createCallbackFcn(app, @OperatingHoursSliderValueChanging, true);
            app.OperatingHoursSlider.Value = 7920;
            app.EditField = uieditfield(app.ChemicalInputsTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 7920;
            app.MassFlowEditFieldLabel = uilabel(app.ChemicalInputsTab);
            app.MassFlowEditFieldLabel.Text = 'Mass Flow';
            app.MassFlowEditField = uieditfield(app.ChemicalInputsTab, 'numeric');
            app.ChemicalSpecificParametersTab = uitab(app.TabGroup);
            app.ChemicalSpecificParametersTab.Title = 'Chemical Specific Parameters';
            app.TextArea_7 = uitextarea(app.ChemicalSpecificParametersTab);
            app.TextArea_7.Value = {'You may choose to modify the defualt paramters by changing the values in the input cell by clicking "Export" after changing the values. The new solution can be obtained using the "Generate Solution" button on the Inputs tab.'};
            app.RunButton = uibutton(app.ChemicalSpecificParametersTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run Button';
            app.CompileInputsButton = uibutton(app.ChemicalSpecificParametersTab, 'push');
            app.CompileInputsButton.ButtonPushedFcn = createCallbackFcn(app, @CompileInputsButtonPushed, true);
            app.CompileInputsButton.Text = 'Compile Inputs';
            app.CRowanUniversityAllRightsReservedLabel_3 = uilabel(app.ChemicalSpecificParametersTab);
            app.CRowanUniversityAllRightsReservedLabel_3.Text = '(C) Rowan University, All Rights Reserved';
            app.Image6_19 = uiimage(app.ChemicalSpecificParametersTab);
            app.Image6_19.ImageSource = 'Rowan Image.jpg';
            app.Image6_20 = uiimage(app.ChemicalSpecificParametersTab);
            app.Image6_20.ImageSource = 'EPA log.png';
            app.Image19_2 = uiimage(app.ChemicalSpecificParametersTab);
            app.Image19_2.ImageSource = 'Superstructure2.jpg';
            app.UITable7 = uitable(app.ChemicalSpecificParametersTab);
            app.UITable7.ColumnName = {'Chemical'; 'FLT (Retention Factor)'; 'ADS1 (Binding)'; 'PRC (efficency)'; 'DST (alpha)'; 'PVP1 (retention Factor)'; 'ADS2 (Binding)'; 'MF1 (retention Factor)'; 'MF2 (retention Factor)'; 'UF1 (Retention Factor)'; 'PVP2 (Retention Factor)'; 'UF2 (Retention Factor)'; 'NF (Retention Factor)'; 'PVP3 (Retention Factor)'};
            app.UITable7.RowName = {};
            app.UITable7.ColumnEditable = [false true true true true true true true true true true true true true];
            app.TechnologySpecificationsTab_2 = uitab(app.TabGroup);
            app.TechnologySpecificationsTab_2.Title = 'Technology Specifications';
            app.TextArea_6 = uitextarea(app.TechnologySpecificationsTab_2);
            app.TextArea_6.Value = {'You may choose to modify the defualt paramters by changing the values in the input cell by clicking "Export" after changing the values. The new solution can be obtained using the "Generate Solution" button on the Inputs tab.'};
            app.DefaultsButton_2 = uibutton(app.TechnologySpecificationsTab_2, 'push');
            app.DefaultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultsButton_2Pushed, true);
            app.DefaultsButton_2.Text = 'Defaults';
            app.UpdateButton = uibutton(app.TechnologySpecificationsTab_2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CRowanUniversityAllRightsReservedLabel_4 = uilabel(app.TechnologySpecificationsTab_2);
            app.CRowanUniversityAllRightsReservedLabel_4.Text = '(C) Rowan University, All Rights Reserved';
            app.Image6_17 = uiimage(app.TechnologySpecificationsTab_2);
            app.Image6_17.ImageSource = 'Rowan Image.jpg';
            app.Image6_18 = uiimage(app.TechnologySpecificationsTab_2);
            app.Image6_18.ImageSource = 'EPA log.png';
            app.SelectTechnologyDropDownLabel = uilabel(app.TechnologySpecificationsTab_2);
            app.SelectTechnologyDropDownLabel.Text = 'Select Technology';
            app.SelectTechnologyDropDown = uidropdown(app.TechnologySpecificationsTab_2);
            app.SelectTechnologyDropDown.Items = {'Adsorption', 'Sedimentation', 'Percipitation', 'Centrifugation', 'Distillation', 'Filter', 'Microfiltation', 'Pervaporation', 'ultrafiltration', 'Nanofiltration'};
            app.SelectTechnologyDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTechnologyDropDownValueChanged, true);
            app.SelectTechnologyDropDown.Value = 'Adsorption';
            app.UITable6 = uitable(app.TechnologySpecificationsTab_2);
            app.UITable6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable6.RowName = {};
            app.Image19 = uiimage(app.TechnologySpecificationsTab_2);
            app.Image19.ImageSource = 'Superstructure2.jpg';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.UIAxes = uiaxes(app.OutputsTab);
            title(app.UIAxes, 'Cost Breakdown per stage')
            ylabel(app.UIAxes, 'Cost (USD)')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.OutputsTab);
            title(app.UIAxes_2, 'Cost Breakdown of all Stages')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UITable3 = uitable(app.OutputsTab);
            app.UITable3.ColumnName = {'Stage'; 'Bypass Used?'; 'Tech'; 'Cost'};
            app.UITable3.RowName = {};
            app.UITable5 = uitable(app.OutputsTab);
            app.UITable5.ColumnName = {'Component'; 'Recovery (kg)'; 'Recovery %'; 'Purity %'; 'Cost per kg'};
            app.UITable5.RowName = {};
            app.UITable4_2 = uitable(app.OutputsTab);
            app.UITable4_2.ColumnName = {'Category'; 'Cost'};
            app.UITable4_2.RowName = {};
            app.StagesUsedinSolutionLabel = uilabel(app.OutputsTab);
            app.StagesUsedinSolutionLabel.Text = 'Stages Used in Solution';
            app.ComponentRecoveryOutputsLabel = uilabel(app.OutputsTab);
            app.ComponentRecoveryOutputsLabel.Text = 'Component Recovery Outputs';
            app.CostBreakdownofallCombinedStagesLabel = uilabel(app.OutputsTab);
            app.CostBreakdownofallCombinedStagesLabel.Text = 'Cost Breakdown of all Combined Stages';
            app.CRowanUniversityAllRightsReservedLabel_5 = uilabel(app.OutputsTab);
            app.CRowanUniversityAllRightsReservedLabel_5.Text = '(C) Rowan University, All Rights Reserved';
            app.Image6_6 = uiimage(app.OutputsTab);
            app.Image6_6.ImageSource = 'Rowan Image.jpg';
            app.Image6_13 = uiimage(app.OutputsTab);
            app.Image6_13.ImageSource = 'EPA log.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Alpha_Solvent_Recovery_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end